classdef app1 < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure            matlab.ui.Figure
		Image_3             matlab.ui.control.Image
		Image_2             matlab.ui.control.Image
		Image               matlab.ui.control.Image
		moodEditField       matlab.ui.control.NumericEditField
		moodEditFieldLabel  matlab.ui.control.Label
		getimageButton      matlab.ui.control.Button
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: getimageButton
		function buttonPress(app, event)
			if (app.moodEditField.Value >= 60)
				app.Image.Visible = 'off';
				app.Image_2.Visible = 'off';
				app.Image_3.Visible = 'on';				
			elseif (app.moodEditField.Value <= 40)
				app.Image.Visible = 'off';
				app.Image_2.Visible = 'on';
				app.Image_3.Visible = 'off';	
			else
				app.Image.Visible = 'on';
				app.Image_2.Visible = 'off';
				app.Image_3.Visible = 'off';	
			end
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.getimageButton = uibutton(app.UIFigure, 'push');
			app.getimageButton.ButtonPushedFcn = createCallbackFcn(app, @buttonPress, true);
			app.getimageButton.Text = 'get image';
			app.moodEditFieldLabel = uilabel(app.UIFigure);
			app.moodEditFieldLabel.Text = 'mood';
			app.moodEditField = uieditfield(app.UIFigure, 'numeric');
			app.moodEditField.Limits = [0 100];
			app.Image = uiimage(app.UIFigure);
			app.Image.Visible = 'off';
			app.Image.ImageSource = 'normal.png';
			app.Image_2 = uiimage(app.UIFigure);
			app.Image_2.Visible = 'off';
			app.Image_2.ImageSource = 'sad.png';
			app.Image_3 = uiimage(app.UIFigure);
			app.Image_3.Visible = 'off';
			app.Image_3.ImageSource = 'happy.png';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = app1

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end